classdef candidate_genes2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TitleLabel                      matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        Part1Tab                        matlab.ui.container.Tab
        DoGeneExpressionCheckBox        matlab.ui.control.CheckBox
        DoKaplanMeierCheckBox           matlab.ui.control.CheckBox
        DoKaplanMeierByClusterCheckBox  matlab.ui.control.CheckBox
        DoKaplanMeierWithMutationCheckBox  matlab.ui.control.CheckBox
        DoMutationCheckBox              matlab.ui.control.CheckBox
        OutputhistogramsbasedoncancersubtypesCheckBox  matlab.ui.control.CheckBox
        NextPart1t2                     matlab.ui.control.Button
        Part2Tab                        matlab.ui.container.Tab
        UploadGenesTableButton          matlab.ui.control.Button
        TextAreaLabel                   matlab.ui.control.Label
        UploadGenesTableTextArea        matlab.ui.control.TextArea
        DropDownLabel                   matlab.ui.control.Label
        DropDown_standard               matlab.ui.control.DropDown
        ChoosethenomenclaturewhichwasusedforthegenesLabel  matlab.ui.control.Label
        DropDown_nomenclature           matlab.ui.control.DropDown
        ChoosesheettoworkonLabel        matlab.ui.control.Label
        DropDown_sheet                  matlab.ui.control.DropDown
        NextPart2t3                     matlab.ui.control.Button
        Part3Tab                        matlab.ui.container.Tab
        PleaseenterdrivergenesnamehastobeaccurateLabel  matlab.ui.control.Label
        DriverGeneTextArea              matlab.ui.control.TextArea
        DriverGeneButton                matlab.ui.control.Button
        ChosenDriverGeneTextArea        matlab.ui.control.TextArea
        GeneAddArrowButton              matlab.ui.control.Button
        GeneDeleteArrowButton           matlab.ui.control.Button
        GenesinfileLabel                matlab.ui.control.Label
        GenesListBox                    matlab.ui.control.ListBox
        ChosengenesListBoxLabel         matlab.ui.control.Label
        ChosenGenesListBox              matlab.ui.control.ListBox
        ChoosewhichimagestooutputLabel  matlab.ui.control.Label
        DropDown_images                 matlab.ui.control.DropDown
        ImagesButton                    matlab.ui.control.Button
        Part4Tab                        matlab.ui.container.Tab
        UploadOutputDir                 matlab.ui.control.Button
        DoneButton                      matlab.ui.control.Button
        ChoosetheoutputdirectoryLabel   matlab.ui.control.Label
        ChooseOutputDirTextArea         matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        do_genes_expression_calculations = 0;
        do_km_analysis = 0;
        do_km_by_clustering = 0;
        do_km_by_mutation_and_expression = 0;      
        do_mutation_analysis = 0;
        do_subtype_histograms = 0;
        gene_expression_filename = 0; 
        sheet_name;
        gene_nomenclature;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Part 1 parameters
            app.DoGeneExpressionCheckBox.Value = 0;
            app.DoKaplanMeierCheckBox.Value = 0;
            app.DoKaplanMeierByClusterCheckBox.Value = 0;
            app.DoKaplanMeierWithMutationCheckBox.Value = 0;
            app.DoMutationCheckBox.Value = 0;
            app.OutputhistogramsbasedoncancersubtypesCheckBox.Value = 0;
            
            % Part 2 parameters
            app.use_cbioportal_not_lab_std = app.DropDown_standard.Value;
            app.gene_nomenclature = app.DropDown_nomenclature.Value;
        end

        % Value changed function: DoGeneExpressionCheckBox
        function DoGeneExpressionCheckBoxValueChanged(app, event)
            app.do_genes_expression_calculations = app.DoGeneExpressionCheckBox.Value;
        end

        % Value changed function: DoKaplanMeierCheckBox
        function DoKaplanMeierCheckBoxValueChanged(app, event)
           app.do_km_analysis = app.DoKaplanMeierCheckBox.Value;
        end

        % Value changed function: DoKaplanMeierByClusterCheckBox
        function DoKaplanMeierByClusterCheckBoxValueChanged(app, event)
            app.do_km_by_clustering = app.DoKaplanMeierByClusterCheckBox.Value;
        end

        % Value changed function: DoKaplanMeierWithMutationCheckBox
        function DoKaplanMeierWithMutationCheckBoxValueChanged(app, event)
            app.do_km_by_mutation_and_expression = app.DoKaplanMeierWithMutationCheckBox.Value;    
        end

        % Value changed function: DoMutationCheckBox
        function DoMutationCheckBoxValueChanged(app, event)
            app.do_mutation_analysis = app.DoMutationCheckBox.Value;
        end

        % Value changed function: 
        % OutputhistogramsbasedoncancersubtypesCheckBox
        function OutputhistogramsbasedoncancersubtypesCheckBoxValueChanged(app, event)
            app.do_subtype_histograms = app.OutputhistogramsbasedoncancersubtypesCheckBox.Value;           
        end

        % Button pushed function: UploadGenesTableButton
        function UploadGenesTableButtonPushed(app, event)
            [filename, path] = uigetfile({'*.xlsx','.xls'},...
                'Please choose your candidate genes file');
            if isequal(filename, 0)
                app.gene_expression_filename = 0;
                app.UploadGenesTableTextArea.Value = {'No file chosen'}; 
                app.DropDown_sheet.Items = {};
                app.DropDown_sheet.Enable = 'off';
            else
                app.gene_expression_filename = fullfile(path, filename);
                app.UploadGenesTableTextArea.Value = {app.gene_expression_filename};
                [~, sheets] = xlsfinfo(app.gene_expression_filename);
                app.DropDown_sheet.Items = sheets;
                app.DropDown_sheet.Enable = 'on';
                app.sheet_name = app.DropDown_sheet.Items{1};
            end
        end

        % Value changed function: DropDown_nomenclature
        function DropDown_nomenclatureValueChanged(app, event)
            app.gene_nomenclature = app.DropDown_nomenclature.Value; 
        end

        % Value changed function: DropDown_sheet
        function DropDown_sheetValueChanged(app, event)
            app.sheet_name = app.DropDown_sheet.Value;
        end

        % Value changed function: DropDown_standard
        function DropDown_standardValueChanged(app, event)
            app.use_cbioportal_not_lab_std = app.DropDown_standard.Value;
            
        end

        % Button pushed function: NextPart2t3
        function NextPart2t3Pushed(app, event)
            if app.gene_expression_filename == 0
                msgbox('Please upload gene expression excel file first!', 'Error', 'error');
                return;
            else
                if strcmp(app.gene_nomenclature, 'entrez') 
                numericData = numericData(:, 2:end); 
%         patientsNames = txtData(1, 2:end);
%         geneNames = numericData(:, 1); 
%     elseif strcmp(gene_nomenclature, 'hugo')
%         % if nomenclature == 'hugo' numericData stays the same.
%         patientsNames = txtData(1, 2:end);
%         geneNames = txtData(2:end, 1); 
%     elseif strcmp(gene_nomenclature, 'both')
%         numericData = numericData(:, 2:end);
%         patientsNames = txtData(1, 3:end);
%         geneNames = txtData(2:end, 1); 
    end
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TitleLabel = uilabel(app.UIFigure);
            app.TitleLabel.Text = 'Candidate Genes Analysis';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Part1Tab = uitab(app.TabGroup);
            app.Part1Tab.Title = 'Part 1';
            app.DoGeneExpressionCheckBox = uicheckbox(app.Part1Tab);
            app.DoGeneExpressionCheckBox.ValueChangedFcn = createCallbackFcn(app, @DoGeneExpressionCheckBoxValueChanged, true);
            app.DoGeneExpressionCheckBox.Text = 'Output analysis of bar plots of gene expression?';
            app.DoKaplanMeierCheckBox = uicheckbox(app.Part1Tab);
            app.DoKaplanMeierCheckBox.ValueChangedFcn = createCallbackFcn(app, @DoKaplanMeierCheckBoxValueChanged, true);
            app.DoKaplanMeierCheckBox.Text = 'Do Kaplan Meier analysis based on gene expression?';
            app.DoKaplanMeierByClusterCheckBox = uicheckbox(app.Part1Tab);
            app.DoKaplanMeierByClusterCheckBox.ValueChangedFcn = createCallbackFcn(app, @DoKaplanMeierByClusterCheckBoxValueChanged, true);
            app.DoKaplanMeierByClusterCheckBox.Text = 'Do Kaplan Meier analysis based on clustering methods?';
            app.DoKaplanMeierWithMutationCheckBox = uicheckbox(app.Part1Tab);
            app.DoKaplanMeierWithMutationCheckBox.ValueChangedFcn = createCallbackFcn(app, @DoKaplanMeierWithMutationCheckBoxValueChanged, true);
            app.DoKaplanMeierWithMutationCheckBox.Text = 'Do Kaplan Meier analysis based on gene expression and mutations?';
            app.DoMutationCheckBox = uicheckbox(app.Part1Tab);
            app.DoMutationCheckBox.ValueChangedFcn = createCallbackFcn(app, @DoMutationCheckBoxValueChanged, true);
            app.DoMutationCheckBox.Text = 'Do mutation analysis?';
            app.OutputhistogramsbasedoncancersubtypesCheckBox = uicheckbox(app.Part1Tab);
            app.OutputhistogramsbasedoncancersubtypesCheckBox.ValueChangedFcn = createCallbackFcn(app, @OutputhistogramsbasedoncancersubtypesCheckBoxValueChanged, true);
            app.OutputhistogramsbasedoncancersubtypesCheckBox.Text = 'Output histograms based on cancer subtypes?';
            app.NextPart1t2 = uibutton(app.Part1Tab, 'push');
            app.NextPart1t2.Text = 'Next Part';
            app.Part2Tab = uitab(app.TabGroup);
            app.Part2Tab.Title = 'Part 2';
            app.UploadGenesTableButton = uibutton(app.Part2Tab, 'push');
            app.UploadGenesTableButton.ButtonPushedFcn = createCallbackFcn(app, @UploadGenesTableButtonPushed, true);
            app.UploadGenesTableButton.Text = 'Upload';
            app.TextAreaLabel = uilabel(app.Part2Tab);
            app.TextAreaLabel.Text = 'Upload a file of the candidate genes & their expression per patient';
            app.UploadGenesTableTextArea = uitextarea(app.Part2Tab);
            app.UploadGenesTableTextArea.Editable = 'off';
            app.DropDownLabel = uilabel(app.Part2Tab);
            app.DropDownLabel.Text = 'Will you use the lab''s standartization of the input files or the cbioportal''s ones?';
            app.DropDown_standard = uidropdown(app.Part2Tab);
            app.DropDown_standard.Items = {'Cbioportal', 'Lab'};
            app.DropDown_standard.ValueChangedFcn = createCallbackFcn(app, @DropDown_standardValueChanged, true);
            app.DropDown_standard.Value = 'Cbioportal';
            app.ChoosethenomenclaturewhichwasusedforthegenesLabel = uilabel(app.Part2Tab);
            app.ChoosethenomenclaturewhichwasusedforthegenesLabel.Text = 'Choose the nomenclature which was used for the genes';
            app.DropDown_nomenclature = uidropdown(app.Part2Tab);
            app.DropDown_nomenclature.Items = {'Both', 'Hugo', 'Entrez'};
            app.DropDown_nomenclature.ValueChangedFcn = createCallbackFcn(app, @DropDown_nomenclatureValueChanged, true);
            app.DropDown_nomenclature.Value = 'Both';
            app.ChoosesheettoworkonLabel = uilabel(app.Part2Tab);
            app.ChoosesheettoworkonLabel.Text = 'Choose sheet to work on:';
            app.DropDown_sheet = uidropdown(app.Part2Tab);
            app.DropDown_sheet.Items = {};
            app.DropDown_sheet.ValueChangedFcn = createCallbackFcn(app, @DropDown_sheetValueChanged, true);
            app.DropDown_sheet.Enable = 'off';
            app.DropDown_sheet.Value = {};
            app.NextPart2t3 = uibutton(app.Part2Tab, 'push');
            app.NextPart2t3.ButtonPushedFcn = createCallbackFcn(app, @NextPart2t3Pushed, true);
            app.NextPart2t3.Text = 'Next Part';
            app.Part3Tab = uitab(app.TabGroup);
            app.Part3Tab.Title = 'Part 3';
            app.PleaseenterdrivergenesnamehastobeaccurateLabel = uilabel(app.Part3Tab);
            app.PleaseenterdrivergenesnamehastobeaccurateLabel.Text = 'Please enter driver gene''s name (has to be accurate!)';
            app.DriverGeneTextArea = uitextarea(app.Part3Tab);
            app.DriverGeneButton = uibutton(app.Part3Tab, 'push');
            app.DriverGeneButton.Enable = 'off';
            app.DriverGeneButton.Text = 'Set name';
            app.ChosenDriverGeneTextArea = uitextarea(app.Part3Tab);
            app.ChosenDriverGeneTextArea.Editable = 'off';
            app.ChosenDriverGeneTextArea.Enable = 'off';
            app.GeneAddArrowButton = uibutton(app.Part3Tab, 'push');
            app.GeneAddArrowButton.Enable = 'off';
            app.GeneAddArrowButton.Text = '→';
            app.GeneDeleteArrowButton = uibutton(app.Part3Tab, 'push');
            app.GeneDeleteArrowButton.Enable = 'off';
            app.GeneDeleteArrowButton.Text = '←';
            app.GenesinfileLabel = uilabel(app.Part3Tab);
            app.GenesinfileLabel.Enable = 'off';
            app.GenesinfileLabel.Text = 'Genes in file ';
            app.GenesListBox = uilistbox(app.Part3Tab);
            app.GenesListBox.Multiselect = 'on';
            app.GenesListBox.Enable = 'off';
            app.GenesListBox.Value = {'Item 1'};
            app.ChosengenesListBoxLabel = uilabel(app.Part3Tab);
            app.ChosengenesListBoxLabel.Enable = 'off';
            app.ChosengenesListBoxLabel.Text = 'Chosen genes';
            app.ChosenGenesListBox = uilistbox(app.Part3Tab);
            app.ChosenGenesListBox.Multiselect = 'on';
            app.ChosenGenesListBox.Enable = 'off';
            app.ChosenGenesListBox.Value = {'Item 1'};
            app.ChoosewhichimagestooutputLabel = uilabel(app.Part3Tab);
            app.ChoosewhichimagestooutputLabel.Enable = 'off';
            app.ChoosewhichimagestooutputLabel.Text = 'Choose which images to output';
            app.DropDown_images = uidropdown(app.Part3Tab);
            app.DropDown_images.Items = {'None', 'All', 'By Specific genes', ''};
            app.DropDown_images.Enable = 'off';
            app.DropDown_images.Value = 'None';
            app.ImagesButton = uibutton(app.Part3Tab, 'push');
            app.ImagesButton.Enable = 'off';
            app.ImagesButton.Text = 'Set image choice';
            app.Part4Tab = uitab(app.TabGroup);
            app.Part4Tab.Title = 'Part 4';
            app.UploadOutputDir = uibutton(app.Part4Tab, 'push');
            app.UploadOutputDir.Text = 'Upload';
            app.DoneButton = uibutton(app.Part4Tab, 'push');
            app.DoneButton.Text = 'Done';
            app.ChoosetheoutputdirectoryLabel = uilabel(app.Part4Tab);
            app.ChoosetheoutputdirectoryLabel.Text = 'Choose the output directory';
            app.ChooseOutputDirTextArea = uitextarea(app.Part4Tab);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = candidate_genes2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end